classdef TEE461Project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        HistogramPanel   matlab.ui.container.Panel
        Sliders          matlab.ui.container.Panel
        Slider_1         matlab.ui.control.Slider
        Slider_2         matlab.ui.control.Slider
        Slider_3         matlab.ui.control.Slider
        Slider_4         matlab.ui.control.Slider
        Slider_5         matlab.ui.control.Slider
        Slider_6         matlab.ui.control.Slider
        Slider_7         matlab.ui.control.Slider
        Slider_8         matlab.ui.control.Slider
        Slider_9         matlab.ui.control.Slider
        UIAxes           matlab.ui.control.UIAxes
        ImagePanel       matlab.ui.container.Panel
        ResetButton      matlab.ui.control.Button
        SaveButton       matlab.ui.control.Button
        LoadButton       matlab.ui.control.Button
        ViewButton       matlab.ui.control.Button
        EqualizeButton   matlab.ui.control.Button
        BrightnessDown   matlab.ui.control.Button
        BrightnessUp     matlab.ui.control.Button
        ContrastDown     matlab.ui.control.Button
        ContrastUp       matlab.ui.control.Button
        BrightnessLabel  matlab.ui.control.Label
        ContrastLabel    matlab.ui.control.Label
        Preview          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function EditSliders(app,event,transform)
            try
                app.Slider_1.Value = app.Slider_1.Value*transform(1);
                app.Slider_2.Value = app.Slider_2.Value*transform(2);
                app.Slider_3.Value = app.Slider_3.Value*transform(3);
                app.Slider_4.Value = app.Slider_4.Value*transform(4);
                app.Slider_5.Value = app.Slider_5.Value*transform(5);
                app.Slider_6.Value = app.Slider_6.Value*transform(6);
                app.Slider_7.Value = app.Slider_7.Value*transform(7);
                app.Slider_8.Value = app.Slider_8.Value*transform(8);
                app.Slider_9.Value = app.Slider_9.Value*transform(9);
                SliderValueChanged(app, event);
            catch
                warning('Too big? rescaling...');
                EditSliders(app,event,repmat(0.5,[1,9]));
                EditSliders(app,event,transform);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider_1, Slider_2, Slider_3, 
        % Slider_4, Slider_5, Slider_6, Slider_7, Slider_9
        function SliderValueChanged(app, event)
            values = [app.Slider_1.Value app.Slider_2.Value app.Slider_3.Value app.Slider_4.Value app.Slider_5.Value app.Slider_6.Value app.Slider_7.Value app.Slider_8.Value app.Slider_9.Value];
            scale = 4;
            skinny = zeros(1,256*scale);
            skinny(1) = values(1);
            for n = 2:9
                skinny(32*scale*(n-1)-1) = values(n);
            end
            
            %std_dev = app.std_devSlider.Value;
            std_dev = 15*scale;
            x = -3*std_dev:1:3*std_dev;
            gauss = exp(-1/2*(x./std_dev).^2);
            
            out = conv(skinny,gauss);
            out = out(3*std_dev+1:3*std_dev+256*scale);
            if sum(out) > 0
                out = out ./ sum((out));
            end
            plot(app.UIAxes,0:1/scale:256-1/scale,out);
            app.UIAxes.XLim = [0 256-1];
            app.UIAxes.YLim = [0 max(out)+0.001]; % 0.001 because 0:0 is possible and illegal
            
            out = out.*255;
            out2equal = zeros(1,256*scale);
            out2equal(1) = out(1);
            for i = 2:256*scale
                out2equal(i) = out2equal(i-1)+out(i);
            end
            
            equal2out = zeros(1,256*scale);
            k = [1,1];
            for i = 1:256*scale
                while (out2equal(k(1)) < i+0.5) && (k(1) < 256*scale)
                    k(1) = k(1) + 1;
                end
                equal2out(i) = (k(1)+k(2))/(2*scale);
                k(2) = k(1);
            end
            
            original2equal = getappdata(app.Preview,'original2equal');
            
            old_image = getappdata(app.Preview,'original_image');
            [M,N] = size(old_image);
            new_image = uint8(zeros(M,N));
            for m = 1:M
                for n = 1:N
                    new_image(m,n) = uint8(round(equal2out(round(1+original2equal(1+old_image(m,n))))));
                    %new_image(m,n) = uint8(old_image(m,n));
                end
            end
%             disp([size(new_image) size(old_image)])
%             disp([original2equal; equal2out]);
%             disp(new_image(round(M/2),100:110))
%             disp(class(new_image));
            imshow(new_image,'Parent',app.Preview);  
            app.ViewButton.Text = "View Original";
            setappdata(app.Preview,'new_image',new_image); 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Open image and save as "image" 
            [filename,~] = uigetfile({'*.tif'},'Open Image'); 
            image = imread(filename); 
            
            % Display image and save within GUI
            setappdata(app.Preview,'original_image',image); 
            ResetButtonPushed(app,event);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            image = getappdata(app.Preview,'original_image');
            imshow(image,'Parent',app.Preview);
            app.ViewButton.Text = "View Original";  
            hist = imhist(image);
            hist = hist/sum(hist);
            simple_hist = zeros(1,9);
            for i = 1:256
                simple_hist(round(i/32)+1) = simple_hist(round(i/32)+1) + hist(i);
                
            end
            simple_hist = simple_hist.*100;
            app.Slider_1.Value = simple_hist(1);
            app.Slider_2.Value = simple_hist(2);
            app.Slider_3.Value = simple_hist(3);
            app.Slider_4.Value = simple_hist(4);
            app.Slider_5.Value = simple_hist(5);
            app.Slider_6.Value = simple_hist(6);
            app.Slider_7.Value = simple_hist(7);
            app.Slider_8.Value = simple_hist(8);
            app.Slider_9.Value = simple_hist(9);
            
            hist = hist.*255;
            original2equal = zeros(1,256);
            original2equal(1) = hist(1);
            for i = 2:256
                original2equal(i) = original2equal(i-1)+hist(i);
            end
            setappdata(app.Preview,'original2equal',original2equal); 
            SliderValueChanged(app, event);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,~] = uiputfile({'*.tif'},'Save Image'); 
            
            % Convert image to uint8 and save 
            image = getappdata(app.Preview,'new_image');
            imwrite(image,filename); 
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            if app.ViewButton.Text == "View Original"
                image = getappdata(app.Preview,'original_image');
                imshow(image,'Parent',app.Preview);
                app.ViewButton.Text = "View Edited";
            elseif app.ViewButton.Text == "View Edited"
                image = getappdata(app.Preview,'new_image');
                imshow(image,'Parent',app.Preview);
                app.ViewButton.Text = "View Original";
            else
                app.ViewButton.Text = "Oops!";
            end
        end

        % Button pushed function: EqualizeButton
        function EqualizeButtonPushed(app, event)
            app.Slider_1.Value = 50;
            app.Slider_2.Value = 50;
            app.Slider_3.Value = 50;
            app.Slider_4.Value = 50;
            app.Slider_5.Value = 50;
            app.Slider_6.Value = 50;
            app.Slider_7.Value = 50;
            app.Slider_8.Value = 50;
            app.Slider_9.Value = 50;
            SliderValueChanged(app, event);
        end

        % Button pushed function: BrightnessUp
        function BrightnessUpButtonPushed(app, event)
            EditSliders(app,event,[0.92,0.94,0.96,0.98,1,1.02,1.04,1.06,1.08]);
        end

        % Button pushed function: BrightnessDown
        function BrightnessDownButtonPushed(app, event)
            EditSliders(app,event,[1.08,1.06,1.04,1.02,1,0.98,0.96,0.94,0.92]);
        end

        % Button pushed function: ContrastUp
        function ContrastUpButtonPushed(app, event)
            EditSliders(app,event,[1.08,1.04,1,0.96,0.92,0.96,1,1.04,1.08]);
        end

        % Button pushed function: ContrastDown
        function ContrastDownButtonPushed(app, event)
            EditSliders(app,event,[0.92,0.96,1,1.04,1.08,1.04,1,0,0.96,0.92]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramPanel = uipanel(app.UIFigure);
            app.HistogramPanel.Title = 'Histogram';
            app.Sliders = uipanel(app.HistogramPanel);
            app.Sliders.Title = 'Histogram Specification Sliders';
            app.Slider_1 = uislider(app.Sliders);
            app.Slider_1.MajorTicks = [];
            app.Slider_1.MajorTickLabels = {''};
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_2 = uislider(app.Sliders);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {''};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.Sliders);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {''};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.Sliders);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {''};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_5 = uislider(app.Sliders);
            app.Slider_5.MajorTicks = [];
            app.Slider_5.MajorTickLabels = {''};
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_5.MinorTicks = [];
            app.Slider_6 = uislider(app.Sliders);
            app.Slider_6.MajorTicks = [];
            app.Slider_6.MajorTickLabels = {''};
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_6.MinorTicks = [];
            app.Slider_7 = uislider(app.Sliders);
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {''};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_8 = uislider(app.Sliders);
            app.Slider_8.MajorTicks = [];
            app.Slider_8.MajorTickLabels = {''};
            app.Slider_8.MinorTicks = [];
            app.Slider_9 = uislider(app.Sliders);
            app.Slider_9.MajorTicks = [];
            app.Slider_9.MajorTickLabels = {''};
            app.Slider_9.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_9.MinorTicks = [];
            app.UIAxes = uiaxes(app.HistogramPanel);
            title(app.UIAxes, 'Histogram Preview')
            app.UIAxes.YLim = [0 255];
            app.UIAxes.YTick = [0 100 200];
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image';
            app.ResetButton = uibutton(app.ImagePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.ImagePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.ImagePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.ViewButton = uibutton(app.ImagePanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View Original';
            app.EqualizeButton = uibutton(app.ImagePanel, 'push');
            app.EqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizeButtonPushed, true);
            app.EqualizeButton.Text = 'Equalize';
            app.BrightnessDown = uibutton(app.ImagePanel, 'push');
            app.BrightnessDown.ButtonPushedFcn = createCallbackFcn(app, @BrightnessDownButtonPushed, true);
            app.BrightnessDown.Text = '-';
            app.BrightnessUp = uibutton(app.ImagePanel, 'push');
            app.BrightnessUp.ButtonPushedFcn = createCallbackFcn(app, @BrightnessUpButtonPushed, true);
            app.BrightnessUp.Text = '+';
            app.ContrastDown = uibutton(app.ImagePanel, 'push');
            app.ContrastDown.ButtonPushedFcn = createCallbackFcn(app, @ContrastDownButtonPushed, true);
            app.ContrastDown.Text = '-';
            app.ContrastUp = uibutton(app.ImagePanel, 'push');
            app.ContrastUp.ButtonPushedFcn = createCallbackFcn(app, @ContrastUpButtonPushed, true);
            app.ContrastUp.Text = '+';
            app.BrightnessLabel = uilabel(app.ImagePanel);
            app.BrightnessLabel.Text = 'Brightness';
            app.ContrastLabel = uilabel(app.ImagePanel);
            app.ContrastLabel.Text = 'Contrast';
            app.Preview = uiaxes(app.ImagePanel);
            title(app.Preview, 'Image Preview')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEE461Project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end