classdef TEE461Project2Dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        CanvasTab                  matlab.ui.container.Tab
        CanvasSizePanel            matlab.ui.container.Panel
        WidthEditFieldLabel        matlab.ui.control.Label
        WidthEditField             matlab.ui.control.NumericEditField
        HeightEditFieldLabel       matlab.ui.control.Label
        HeightEditField            matlab.ui.control.NumericEditField
        BackgroundColorPanel       matlab.ui.container.Panel
        REditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        GEditFieldLabel            matlab.ui.control.Label
        GEditField                 matlab.ui.control.NumericEditField
        BEditFieldLabel            matlab.ui.control.Label
        BEditField                 matlab.ui.control.NumericEditField
        SizeandPositionTab         matlab.ui.container.Tab
        LayerSizePanel             matlab.ui.container.Panel
        WidthEditField_2Label      matlab.ui.control.Label
        WidthEditField_2           matlab.ui.control.NumericEditField
        HeightEditField_2Label     matlab.ui.control.Label
        HeightEditField_2          matlab.ui.control.NumericEditField
        ScaleEditFieldLabel        matlab.ui.control.Label
        ScaleEditField             matlab.ui.control.NumericEditField
        UseascanvassizeCheckBox    matlab.ui.control.CheckBox
        ScaletofitCheckBox         matlab.ui.control.CheckBox
        LayerShiftPanel            matlab.ui.container.Panel
        YOffsetEditFieldLabel      matlab.ui.control.Label
        YOffsetEditField           matlab.ui.control.NumericEditField
        XOffsetEditFieldLabel      matlab.ui.control.Label
        XOffsetEditField           matlab.ui.control.NumericEditField
        ImageColorTab              matlab.ui.container.Tab
        ImageColorOptionsPanel     matlab.ui.container.Panel
        BrightnessSliderLabel      matlab.ui.control.Label
        BrightnessSlider           matlab.ui.control.Slider
        SaturationSliderLabel      matlab.ui.control.Label
        SaturationSlider           matlab.ui.control.Slider
        MaskParametersTab          matlab.ui.container.Tab
        HuePanel                   matlab.ui.container.Panel
        ToleranceEditFieldLabel    matlab.ui.control.Label
        HueToleranceEditField      matlab.ui.control.NumericEditField
        ValueEditFieldLabel        matlab.ui.control.Label
        HueValueEditField          matlab.ui.control.NumericEditField
        SaturationPanel            matlab.ui.container.Panel
        ToleranceEditField_2Label  matlab.ui.control.Label
        SatToleranceEditField      matlab.ui.control.NumericEditField
        ValueEditField_2Label      matlab.ui.control.Label
        SatValueEditField          matlab.ui.control.NumericEditField
        IntensityPanel             matlab.ui.container.Panel
        ToleranceEditField_3Label  matlab.ui.control.Label
        IntToleranceEditField      matlab.ui.control.NumericEditField
        ValueEditField_3Label      matlab.ui.control.Label
        IntValueEditField          matlab.ui.control.NumericEditField
        ProximityPanel             matlab.ui.container.Panel
        DistanceEditFieldLabel     matlab.ui.control.Label
        DistanceEditField          matlab.ui.control.NumericEditField
        OptionDropDownLabel        matlab.ui.control.Label
        OptionDropDown             matlab.ui.control.DropDown
        ApplyButton                matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % this refers to the main app for use of it's public functions.
        Tab % the tab that the program should be functioning in
        ImageSize % remembers the size of the image for scaling purpouses
        newMask = false % true if mask changed.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, tab)
            % add a way to keep track of layer when called?
            app.CallingApp = mainapp;
            app.Tab = tab;
            h = app.TabGroup.Children(tab);
            app.TabGroup.SelectedTab = h;
            app.UIFigure.Name = app.TabGroup.SelectedTab.Title;
            
            % canvas settings
            info = mainapp.getCanvasInfo();
            
            app.HeightEditField.Value = info(1);
            app.WidthEditField.Value = info(2);
            
            app.REditField.Value = 255*info(3);
            app.GEditField.Value = 255*info(4);
            app.BEditField.Value = 255*info(5);

            % layer size settings
            info = mainapp.getSizeInfo();
            
            app.ImageSize(1) = info(1);
            app.ImageSize(2) = info(2);
            app.ImageSize(3) = info(1)/info(2);
            
            app.HeightEditField_2.Value = info(1) * info(3);
            app.WidthEditField_2.Value = info(2) * info(3);
            
            app.ScaleEditField.Value = info(3) * 100;
            
            app.XOffsetEditField.Value = info(4);
            app.YOffsetEditField.Value = info(5);
            
            app.XOffsetEditField.Limits = [-app.ImageSize(1) app.HeightEditField.Value];
            app.YOffsetEditField.Limits = [-app.ImageSize(2) app.WidthEditField.Value];

            % layer color settings
            info = mainapp.getColorInfo();
            
            app.BrightnessSlider.Value = info(1) * 100;
            app.SaturationSlider.Value = info(2) * 100;

            % mask paramerer settings
            info = mainapp.getMaskInfo();
            
            app.HueValueEditField.Value = info(1)*100;
            app.HueToleranceEditField.Value = info(2)*100;
            app.SatValueEditField.Value = info(3)*100;
            app.SatToleranceEditField.Value = info(4)*100;
            app.IntValueEditField.Value = info(5)*100;
            app.IntToleranceEditField.Value = info(6)*100;
            
        end

        % Close request function: UIFigure
        function DialogCloseRequest(app, event)
            %do any toggling in the original app
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            DialogCloseRequest(app,event);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.UseascanvassizeCheckBox.Value == true
                app.HeightEditField.Value = app.HeightEditField_2.Value;
                app.WidthEditField.Value = app.WidthEditField_2.Value;
                app.UseascanvassizeCheckBox.Value = false;
            end
            info = [app.HeightEditField.Value, app.WidthEditField.Value , app.REditField.Value/255, app.GEditField.Value/255, app.BEditField.Value/255];
            app.CallingApp.setCanvasInfo(info);
            
            info = [app.XOffsetEditField.Value, app.YOffsetEditField.Value, app.ScaleEditField.Value/100];
            app.CallingApp.setSizeInfo(info);
            
            app.CallingApp.setColorInfo([app.BrightnessSlider.Value/100,app.SaturationSlider.Value/100]);
            
            if app.newMask
                app.CallingApp.setMaskInfo([app.HueValueEditField.Value/100,app.HueToleranceEditField.Value/100,app.SatValueEditField.Value/100,app.SatToleranceEditField.Value/100,app.IntValueEditField.Value/100,app.IntToleranceEditField.Value/100]);
            end
            app.newMask = false;
            
            app.CallingApp.previewImage();
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            ApplyButtonPushed(app,event);
            CancelButtonPushed(app,event);
        end

        % Value changed function: ScaletofitCheckBox
        function ScaletofitCheckBoxValueChanged(app, event)
            value = app.ScaletofitCheckBox.Value;
            if value == true
                app.HeightEditField_2.Editable = false;
                app.WidthEditField_2.Editable = false;
                app.ScaleEditField.Editable = false;
                ch = app.HeightEditField.Value;
                cw = app.WidthEditField.Value;
                lh = app.ImageSize(1);
                lw = app.ImageSize(2);
                rat = min(ch/lh,cw/lw);
                
                app.ScaleEditField.Value = rat*100;
                app.HeightEditField_2.Value = lh*rat;
                app.WidthEditField_2.Value = lw*rat;
            else
                app.HeightEditField_2.Editable = true;
                app.WidthEditField_2.Editable = true;
                app.ScaleEditField.Editable = true;                
            end
        end

        % Value changed function: HeightEditField_2
        function HeightEditField_2ValueChanged(app, event)
            value = app.HeightEditField_2.Value;
            app.WidthEditField_2.Value = value / app.ImageSize(3);
            app.ScaleEditField.Value = 100*value/app.ImageSize(1);
        end

        % Value changed function: WidthEditField_2
        function WidthEditField_2ValueChanged(app, event)
            value = app.WidthEditField_2.Value;
            app.HeightEditField_2.Value = value * app.ImageSize(3);
            app.ScaleEditField.Value = 100*value/app.ImageSize(2);
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            value = app.ScaleEditField.Value/100;
            app.HeightEditField_2.Value = value * app.ImageSize(1);
            app.WidthEditField_2.Value = value * app.ImageSize(2);
        end

        % Value changed function: HueToleranceEditField, 
        % HueValueEditField, IntToleranceEditField, 
        % IntValueEditField, SatToleranceEditField, 
        % SatValueEditField
        function MaskChanged(app, event)
            app.newMask = true;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CanvasTab = uitab(app.TabGroup);
            app.CanvasTab.Title = 'Canvas';
            app.CanvasSizePanel = uipanel(app.CanvasTab);
            app.CanvasSizePanel.Title = 'Canvas Size';
            app.WidthEditFieldLabel = uilabel(app.CanvasSizePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.CanvasSizePanel, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.RoundFractionalValues = 'on';
            app.HeightEditFieldLabel = uilabel(app.CanvasSizePanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.CanvasSizePanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.RoundFractionalValues = 'on';
            app.BackgroundColorPanel = uipanel(app.CanvasTab);
            app.BackgroundColorPanel.Title = 'Background Color';
            app.REditFieldLabel = uilabel(app.BackgroundColorPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.BackgroundColorPanel, 'numeric');
            app.REditField.Limits = [0 255];
            app.REditField.RoundFractionalValues = 'on';
            app.GEditFieldLabel = uilabel(app.BackgroundColorPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.BackgroundColorPanel, 'numeric');
            app.GEditField.Limits = [0 255];
            app.GEditField.RoundFractionalValues = 'on';
            app.BEditFieldLabel = uilabel(app.BackgroundColorPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.BackgroundColorPanel, 'numeric');
            app.BEditField.Limits = [0 255];
            app.BEditField.RoundFractionalValues = 'on';
            app.SizeandPositionTab = uitab(app.TabGroup);
            app.SizeandPositionTab.Title = 'Size and Position';
            app.LayerSizePanel = uipanel(app.SizeandPositionTab);
            app.LayerSizePanel.Title = 'Layer Size';
            app.WidthEditField_2Label = uilabel(app.LayerSizePanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.LayerSizePanel, 'numeric');
            app.WidthEditField_2.Limits = [0 Inf];
            app.WidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthEditField_2ValueChanged, true);
            app.HeightEditField_2Label = uilabel(app.LayerSizePanel);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.LayerSizePanel, 'numeric');
            app.HeightEditField_2.Limits = [0 Inf];
            app.HeightEditField_2.ValueChangedFcn = createCallbackFcn(app, @HeightEditField_2ValueChanged, true);
            app.ScaleEditFieldLabel = uilabel(app.LayerSizePanel);
            app.ScaleEditFieldLabel.Text = 'Scale (%)';
            app.ScaleEditField = uieditfield(app.LayerSizePanel, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.UseascanvassizeCheckBox = uicheckbox(app.LayerSizePanel);
            app.UseascanvassizeCheckBox.Text = 'Use as canvas size';
            app.ScaletofitCheckBox = uicheckbox(app.LayerSizePanel);
            app.ScaletofitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaletofitCheckBoxValueChanged, true);
            app.ScaletofitCheckBox.Text = 'Scale to fit';
            app.LayerShiftPanel = uipanel(app.SizeandPositionTab);
            app.LayerShiftPanel.Title = 'Layer Shift';
            app.YOffsetEditFieldLabel = uilabel(app.LayerShiftPanel);
            app.YOffsetEditFieldLabel.Text = 'Y Offset';
            app.YOffsetEditField = uieditfield(app.LayerShiftPanel, 'numeric');
            app.XOffsetEditFieldLabel = uilabel(app.LayerShiftPanel);
            app.XOffsetEditFieldLabel.Text = 'X Offset';
            app.XOffsetEditField = uieditfield(app.LayerShiftPanel, 'numeric');
            app.ImageColorTab = uitab(app.TabGroup);
            app.ImageColorTab.Title = 'Image Color';
            app.ImageColorOptionsPanel = uipanel(app.ImageColorTab);
            app.ImageColorOptionsPanel.Title = 'Image Color Options';
            app.BrightnessSliderLabel = uilabel(app.ImageColorOptionsPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ImageColorOptionsPanel);
            app.BrightnessSlider.Limits = [-100 100];
            app.SaturationSliderLabel = uilabel(app.ImageColorOptionsPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.ImageColorOptionsPanel);
            app.SaturationSlider.Limits = [-100 100];
            app.MaskParametersTab = uitab(app.TabGroup);
            app.MaskParametersTab.Title = 'Mask Parameters';
            app.HuePanel = uipanel(app.MaskParametersTab);
            app.HuePanel.Title = 'Hue';
            app.ToleranceEditFieldLabel = uilabel(app.HuePanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.HueToleranceEditField = uieditfield(app.HuePanel, 'numeric');
            app.HueToleranceEditField.Limits = [0 100];
            app.HueToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @MaskChanged, true);
            app.ValueEditFieldLabel = uilabel(app.HuePanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.HueValueEditField = uieditfield(app.HuePanel, 'numeric');
            app.HueValueEditField.Limits = [0 100];
            app.HueValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaskChanged, true);
            app.SaturationPanel = uipanel(app.MaskParametersTab);
            app.SaturationPanel.Title = 'Saturation';
            app.ToleranceEditField_2Label = uilabel(app.SaturationPanel);
            app.ToleranceEditField_2Label.Text = 'Tolerance';
            app.SatToleranceEditField = uieditfield(app.SaturationPanel, 'numeric');
            app.SatToleranceEditField.Limits = [0 100];
            app.SatToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @MaskChanged, true);
            app.ValueEditField_2Label = uilabel(app.SaturationPanel);
            app.ValueEditField_2Label.Text = 'Value';
            app.SatValueEditField = uieditfield(app.SaturationPanel, 'numeric');
            app.SatValueEditField.Limits = [0 100];
            app.SatValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaskChanged, true);
            app.IntensityPanel = uipanel(app.MaskParametersTab);
            app.IntensityPanel.Title = 'Intensity';
            app.ToleranceEditField_3Label = uilabel(app.IntensityPanel);
            app.ToleranceEditField_3Label.Text = 'Tolerance';
            app.IntToleranceEditField = uieditfield(app.IntensityPanel, 'numeric');
            app.IntToleranceEditField.Limits = [0 100];
            app.IntToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @MaskChanged, true);
            app.ValueEditField_3Label = uilabel(app.IntensityPanel);
            app.ValueEditField_3Label.Text = 'Value';
            app.IntValueEditField = uieditfield(app.IntensityPanel, 'numeric');
            app.IntValueEditField.Limits = [0 100];
            app.IntValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaskChanged, true);
            app.ProximityPanel = uipanel(app.MaskParametersTab);
            app.ProximityPanel.Title = 'Proximity';
            app.ProximityPanel.Visible = 'off';
            app.DistanceEditFieldLabel = uilabel(app.ProximityPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.ProximityPanel, 'numeric');
            app.OptionDropDownLabel = uilabel(app.ProximityPanel);
            app.OptionDropDownLabel.Text = 'Option';
            app.OptionDropDown = uidropdown(app.ProximityPanel);
            app.OptionDropDown.Items = {'Don''t Use Proximity', 'Only Check Proximity of Original', 'Only Check Proximity of Found Pixels'};
            app.OptionDropDown.Value = 'Don''t Use Proximity';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEE461Project2Dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end