classdef TEE461Project2Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewMenu                    matlab.ui.container.Menu
        PreviewMenu                matlab.ui.container.Menu
        RenderMenu                 matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        LayerMenu                  matlab.ui.container.Menu
        NewLayerMenu               matlab.ui.container.Menu
        RemoveLayerMenu            matlab.ui.container.Menu
        NavigateTopMenu            matlab.ui.container.Menu
        NavigateUpwardMenu         matlab.ui.container.Menu
        NavigateDownwardMenu       matlab.ui.container.Menu
        NavigateBottomMenu         matlab.ui.container.Menu
        NavigateBackgroundMenu     matlab.ui.container.Menu
        BringForwardMenu           matlab.ui.container.Menu
        SendBackwardMenu           matlab.ui.container.Menu
        ImageMenu                  matlab.ui.container.Menu
        SetCanvasSizeandColorMenu  matlab.ui.container.Menu
        ImportImageMenu            matlab.ui.container.Menu
        ShiftandScaleMenu          matlab.ui.container.Menu
        PickMaskValueMenu          matlab.ui.container.Menu
        SetMaskParametersMenu      matlab.ui.container.Menu
        ColorAdjustmentsMenu       matlab.ui.container.Menu
        ImagePreview               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        layer_count = 0; % number of layers, starting at 0.
        current_layer = 0;
        base_imgs = zeros(1,1,4,1); % array containing the base image for each layer, now includes the mask as layer 4
        img_offsets; % x offset, y offset, and scale change for each image.
        img_size; % the size of a base image
        mask_params; % hue, saturation, and brightness thresholds.
        mask_values; % hue, sat, and val from selected point.
        canvas_size = [1040,1040]; % the size of the final image produced
        preview_scale = 0.25
        bkg_color; % default pixel color if no image overwrites it.
        reason = "na"; % the reson for using a menu so results can be properly interpreted.
        DialogApp % Description
        color_properties % brightness(1) and contrast(2) for each layer
    end
    
    methods (Access = private)
        
        % consider editign these so the dialation and erosion can
        % accomodate non-uniform kernels
        function out = erode(~,in,B)
            [M,N] = size(B);
            eroding = conv2(in,B);
            out = floor(eroding((M+1)/2:end-(M-1)/2,(N+1)/2:end-(N-1)/2)/(M*N));
        end
        
        function out = dialate(~,in,B)
            [M,N] = size(B);
            dialating = conv2(in,B);
            out = ceil(dialating((M+1)/2:end-(M-1)/2,(N+1)/2:end-(N-1)/2)/(M*N));
        end
        
        function img = combineLayers(app)
            tic
            M = round(app.canvas_size(1)*app.preview_scale);
            N = round(app.canvas_size(2)*app.preview_scale);
            img = repmat(app.bkg_color,M,N,1);
            base_size = size(app.base_imgs);
            % for each row
            for m = 1:M
                % and each column
                for n = 1:N
                    % start at the highest value layer and decend
                    for l = app.layer_count:-1:1
                        % find the pixel that would be used for this image
                        pixel_check = round(([m;n]/app.preview_scale-app.img_offsets(1:2,l))/app.img_offsets(3,l));
                        % check if the pixel will show 
                        % (if the mask == 1 and is in bounds)
                        if pixel_check(1) <= base_size(1) && pixel_check(2) <= base_size(2) && pixel_check(1) > 0 && pixel_check(2) > 0 && app.base_imgs(pixel_check(1),pixel_check(2),4,l) == 1
                            % draw the pixel and stop going through layers
                            pix(1,1,:) = app.base_imgs(pixel_check(1),pixel_check(2),1:3,l);
                            pix = rgb2hsv(pix);
                            %pix(2) = pix(2)*(1+app.color_properties(2,l));
                            pix(:,:,2) = pix(:,:,2)^(10^(app.color_properties(2,l)));
                            pix = hsv2rgb(pix);
                            pix = pix.^(10^(-app.color_properties(1,l)));
                            img(m,n,:) = pix;
                            break
                        end
                    end
                    if sum(imag(img(m,n,:))) ~= 0
                        disp(img(m,n,:))
                        disp(m)
                        disp(n)
                        disp(l)
                    end
                end
            end
            assignin('base','img',img);
            imshow(img,'Parent',app.ImagePreview);
            fprintf("Time Elapsed :%.2f",toc);
        end
        
        function layerMove(app,to,from)
            app.base_imgs(:,:,:,to) = app.base_imgs(:,:,:,from);
            app.img_offsets(:,to) = app.img_offsets(:,from);
            app.img_size(:,to) = app.img_size(:,from);
            app.mask_params(:,to) = app.mask_params(:,from);
            app.mask_values(:,to) = app.mask_values(:,from);
        end
        
        function layerSwap(app,A,B)
            temp_bi = app.base_imgs(:,:,:,A);
            temp_io = app.img_offsets(:,A);
            temp_is = app.img_size(:,A);
            temp_mp = app.mask_params(:,A);
            temp_mv = app.mask_values(:,A);
            
            app.base_imgs(:,:,:,A) = app.base_imgs(:,:,:,B);
            app.img_offsets(:,A) = app.img_offsets(:,B);
            app.img_size(:,A) = app.img_size(:,B);
            app.mask_params(:,A) = app.mask_params(:,B);
            app.mask_values(:,A) = app.mask_values(:,B);
            
            app.base_imgs(:,:,:,B) = temp_bi;
            app.img_offsets(:,B) = temp_io;
            app.img_size(:,B) = temp_is;
            app.mask_params(:,B) = temp_mp;
            app.mask_values(:,B) = temp_mv;
        end
        
        function loadLayer(app,in)
            app.current_layer = in;
            if in == 0
                displayBackground(app);
            else
                displayLayer(app);
            end
        end
        
        function displayBackground(app)
            M = app.canvas_size(1);
            N = app.canvas_size(2);
            bkg = repmat(app.bkg_color,M,N,1);
            imshow(bkg,'Parent',app.ImagePreview);
            app.ImagePreview.Title.String = "Background";
        end
        
        function displayLayer(app)
            M = round(app.canvas_size(1)*app.preview_scale);
            N = round(app.canvas_size(2)*app.preview_scale);
            l = app.current_layer;
            img = repmat(app.bkg_color,M,N,1);
            base_size = size(app.base_imgs);
            for m = 1:M
                for n = 1:N
                    pixel_check = round(([m;n]/app.preview_scale-app.img_offsets(1:2,l))/app.img_offsets(3,l));
                    if pixel_check(1) <= base_size(1) && pixel_check(2) <= base_size(2) && pixel_check(1) > 0 && pixel_check(2) > 0 && app.base_imgs(pixel_check(1),pixel_check(2),4,l) == 1
                        img(m,n,:) = app.base_imgs(pixel_check(1),pixel_check(2),1:3,l);
                    end
                end
            end
            img = rgb2hsv(img);
            %img(:,:,2) = img(:,:,2)*(1+app.color_properties(2,l));
            img(:,:,2) = img(:,:,2)^(10^(app.color_properties(2,l)));
            img = hsv2rgb(img);
            img = img.^(10^(-app.color_properties(1,l)));
            imshow(img,'Parent',app.ImagePreview);
            app.ImagePreview.Title.String = "Layer " + string(app.current_layer);
        end
        
        %make this a bit more robust
        function enableCheck(app)
            h = findobj(app.UIFigure.Children,'Tag','NeedsLayers');
            if app.layer_count > 0
                for i = 1:size(h)
                    h(i).Enable = true;
                end
            else
                for i = 1:size(h)
                    h(i).Enable = false;
                end
            end
            h = findobj(app.UIFigure.Children,'Tag','NeedsTwoLayers');
            if app.layer_count > 1
                for i = 1:size(h)
                    h(i).Enable = true;
                end
            else
                for i = 1:size(h)
                    h(i).Enable = false;
                end
            end
        end
        
        % this function is borrowed from the internet
        function [x, y] = selectDataPoints(~, ax)
            roi = drawpoint(ax);
            x = roi.Position(1);
            y = roi.Position(2);
        end
        
        function makeMask(app)
            l = app.current_layer;
            M = app.img_size(1,l);
            N = app.img_size(2,l);
            rgb = app.base_imgs(1:M,1:N,1:3,l);
            hsv = rgb2hsv(rgb);
            mask = zeros(M,N);
            disp("make mask vals");
            disp(app.mask_values)
            disp("params");
            disp(app.mask_params)
            for m = 1:M
                for n = 1:N
                    if ( ...
                            ( ...
                                ( ...
                                    ( ...
                                        hsv(m,n,1) >= (app.mask_values(1,l)-app.mask_params(1,l)/2) ...
                                    ) && ( ...
                                        hsv(m,n,1) <= (app.mask_values(1,l)+app.mask_params(1,l)/2) ...
                                    ) ...
                                ) || ( ...
                                    ( ...
                                        hsv(m,n,1) >= (app.mask_values(1,l)-app.mask_params(1,l)/2+1) ...
                                    ) && ( ...
                                        hsv(m,n,1) <= (app.mask_values(1,l)+app.mask_params(1,l)/2+1) ...
                                    ) ...
                                ) || ( ...
                                    ( ...
                                        hsv(m,n,1) >= (app.mask_values(1,l)-app.mask_params(1,l)/2-1) ...
                                    ) && ( ...
                                        hsv(m,n,1) <= (app.mask_values(1,l)+app.mask_params(1,l)/2-1) ...
                                    ) ...
                                ) ...
                            ) && (hsv(m,n,2) >= (app.mask_values(2,l)-app.mask_params(2,l)/2)) && (hsv(m,n,2) <= (app.mask_values(2,l)+app.mask_params(2,l)/2)) && (hsv(m,n,3) >= (app.mask_values(3,l)-app.mask_params(3,l)/2)) && (hsv(m,n,3) <= (app.mask_values(3,l)+app.mask_params(3,l)/2)))
                        mask(m,n) = 1;
                    end
                end
            end
            B = ones(15);
            mask = dialate(app,erode(app,mask,B),B);
            app.base_imgs(1:M,1:N,4,l) = mask;
            loadLayer(app,app.current_layer);
        end
    end
    
    methods (Access = public)
        
        function info = getCanvasInfo(app)
            info(1) = app.canvas_size(1);
            info(2) = app.canvas_size(2);
            
            info(3) = app.bkg_color(1,1,1);
            info(4) = app.bkg_color(1,1,2);
            info(5) = app.bkg_color(1,1,3);
        end
        
        function setCanvasInfo(app,info)
            app.canvas_size(1) = info(1);
            app.canvas_size(2) = info(2);
            
            app.bkg_color(1,1,1) = info(3);
            app.bkg_color(1,1,2) = info(4);
            app.bkg_color(1,1,3) = info(5);
        end
        
        function info = getSizeInfo(app)
            l = app.current_layer;
            info(1) = app.img_size(1,l);
            info(2) = app.img_size(2,l);
            
            info(3) = app.img_offsets(3,l);
            
            info(4) = app.img_offsets(1,l);
            info(5) = app.img_offsets(2,l);
        end
        
        function setSizeInfo(app,info)
            l = app.current_layer;
            app.img_offsets(1,l) = info(1);
            app.img_offsets(2,l) = info(2);
            app.img_offsets(3,l) = info(3);
        end
        
        function info = getColorInfo(app)
            l = app.current_layer;
            info(1) = app.color_properties(1,l);
            info(2) = app.color_properties(2,l);
        end
        
        function setColorInfo(app,info)
            l = app.current_layer;
            app.color_properties(1,l) = info(1);
            app.color_properties(2,l) = info(2);
        end
        
        function info = getMaskInfo(app)
            l = app.current_layer;
            info = [app.mask_values(1,l), app.mask_params(1,l), app.mask_values(2,l), app.mask_params(2,l), app.mask_values(3,l), app.mask_params(3,l)];
        end
        
        function setMaskInfo(app,info)
            l = app.current_layer;
            app.mask_values(1,l) = info(1);
            app.mask_params(1,l) = info(2);
            app.mask_values(2,l) = info(3);
            app.mask_params(2,l) = info(4);
            app.mask_values(3,l) = info(5);
            app.mask_params(3,l) = info(6);
        
            makeMask(app);
        end
        
        function previewImage(app)
            loadLayer(app,app.current_layer);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.bkg_color(1,1,:) = [1,1,1];
            displayBackground(app);
            enableCheck(app);
            NewLayer(app);
            % Open a configuration diologue that allows you to set canvas
            % size, background color, then maybe start the first layer.
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % Throw out all changes, revert as if the app was restarted.
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename,~] = uiputfile({'*.tif'},'Save Image'); 
            
            temp = app.preview_scale;
            app.preview_scale = 1;
            image = combineLayers(app);
            app.preview_scale = temp;
            
            imwrite(image,filename); 
            app.ImagePreview.Title.String = "Saved";
        end

        % Menu selected function: PreviewMenu
        function PreviewMenuSelected(app, event)
            combineLayers(app);
            %imshow(combineLayers(app),'Parent',app.ImagePreview);
            app.ImagePreview.Title.String = "Image Preview";
        end

        % Menu selected function: NewLayerMenu
        function NewLayer(app, event)
            [M,N,~,~] = size(app.base_imgs);
            app.layer_count = app.layer_count + 1;
            % make a new blank base_img
            app.base_imgs(:,:,:,app.layer_count) = zeros(M,N,4,1);
            app.img_offsets(:,app.layer_count) = [0 0 1];
            app.mask_values(:,app.layer_count) = [0.5,0.5,0.5];
            app.mask_params(:,app.layer_count) = [1,1,1];
            app.color_properties(:,app.layer_count) = [0,0];
            loadLayer(app,app.layer_count);
            enableCheck(app);
        end

        % Menu selected function: RemoveLayerMenu
        function RemoveLayerMenuSelected(app, event)
            if app.layer_count > 0
                for l = app.current_layer:app.layer_count-1
                    layerMove(app,l,l+1);
                end
                % Remove the last entry of the list (not neccesairy?)
                app.base_imgs = app.base_imgs(:,:,:,1:end-1);
                app.layer_count = app.layer_count - 1;
                app.current_layer = app.current_layer - 1;
                loadLayer(app,app.current_layer);
            end
            enableCheck(app);
        end

        % Menu selected function: NavigateTopMenu
        function NavigateTopMenuSelected(app, event)
            loadLayer(app,app.layer_count);
        end

        % Menu selected function: NavigateUpwardMenu
        function NavigateUpwardMenuSelected(app, event)
            if app.current_layer < app.layer_count
                loadLayer(app,app.current_layer + 1);
            end
        end

        % Menu selected function: NavigateDownwardMenu
        function NavigateDownwardMenuSelected(app, event)
            if app.current_layer > 0
                loadLayer(app,app.current_layer - 1);
            end
        end

        % Menu selected function: NavigateBottomMenu
        function NavigateBottomMenuSelected(app, event)
            loadLayer(app,1);
        end

        % Menu selected function: NavigateBackgroundMenu
        function NavigateBackgroundMenuSelected(app, event)
            loadLayer(app,0);
        end

        % Menu selected function: BringForwardMenu
        function BringForwardMenuSelected(app, event)
            layerSwap(app,app.current_layer,app.current_layer + 1)
        end

        % Menu selected function: SendBackwardMenu
        function SendBackwardMenuSelected(app, event)
            layerSwap(app,app.current_layer,app.current_layer - 1)
        end

        % Menu selected function: SetCanvasSizeandColorMenu
        function SetCanvasSizeandColorMenuSelected(app, event)
            app.DialogApp = TEE461Project2Dialog(app,1);
        end

        % Menu selected function: ImportImageMenu
        function ImportImageMenuSelected(app, event)
            [filename,~] = uigetfile({'*.tif'},'Open Image'); 
            image = im2double(imread(filename));
            [m,n,d] = size(image);
            app.img_size(1:2,app.current_layer) = [m n];
            if d == 1
                image = repmat(image,1,1,3);
            end
            [M,N,~,~] = size(app.base_imgs);
            % make a new array big enough to fit both the old and the new image sizes.
            % zeros are used because it makes unwritten data transparent.
            temp = zeros(max(M,m),max(N,n),4,app.layer_count);
            % set the new array to have all the data the old array had.
            temp(1:M,1:N,:,:) = app.base_imgs; 
            % clear the row we are writing to for new data
            temp(:,:,:,app.current_layer) = zeros(max(M,m),max(N,n),4,1);
            % write in the new image and set the masks to 1:
            temp(1:m,1:n,1:3,app.current_layer) = image;
            temp(1:m,1:n,4,app.current_layer) = ones(m,n);
            app.base_imgs = temp;
            loadLayer(app,app.current_layer);
        end

        % Menu selected function: ShiftandScaleMenu
        function ShiftandScaleMenuSelected(app, event)
            app.DialogApp = TEE461Project2Dialog(app,2);
        end

        % Menu selected function: PickMaskValueMenu
        function PickMaskValueMenuSelected(app, event)
            l = app.current_layer;
            M = app.img_size(1,l);
            N = app.img_size(2,l);
            rgb = app.base_imgs(1:M,1:N,1:3,l);
            hsv = rgb2hsv(rgb);
            figure(1);
            imshow(rgb);
            title("Select a Color");
            point = ginput(1);
            close(1);
            app.mask_values(1,l) = hsv(round(point(2)),round(point(1)),1);
            app.mask_params(1,l) = 0.1;
            makeMask(app);
        end

        % Menu selected function: SetMaskParametersMenu
        function SetMaskParametersMenuSelected(app, event)
            app.DialogApp = TEE461Project2Dialog(app,4);
        end

        % Close request function: UIFigure
        function MainCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Menu selected function: ColorAdjustmentsMenu
        function ColorAdjustmentsMenuSelected(app, event)
            app.DialogApp = TEE461Project2Dialog(app,3);
        end

        % Menu selected function: RenderMenu
        function RenderMenuSelected(app, event)
            temp = app.preview_scale;
            app.preview_scale = 1;
            combineLayers(app);
            app.preview_scale = temp;
            %imshow(combineLayers(app),'Parent',app.ImagePreview);
            app.ImagePreview.Title.String = "Final Image";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Visible = 'off';
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.PreviewMenu = uimenu(app.FileMenu);
            app.PreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @PreviewMenuSelected, true);
            app.PreviewMenu.Accelerator = 'P';
            app.PreviewMenu.Text = 'Preview';
            app.RenderMenu = uimenu(app.FileMenu);
            app.RenderMenu.MenuSelectedFcn = createCallbackFcn(app, @RenderMenuSelected, true);
            app.RenderMenu.Accelerator = 'E';
            app.RenderMenu.Text = 'Render';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.LayerMenu = uimenu(app.UIFigure);
            app.LayerMenu.Text = 'Layer';
            app.NewLayerMenu = uimenu(app.LayerMenu);
            app.NewLayerMenu.MenuSelectedFcn = createCallbackFcn(app, @NewLayer, true);
            app.NewLayerMenu.Accelerator = 'L';
            app.NewLayerMenu.Text = 'New Layer';
            app.RemoveLayerMenu = uimenu(app.LayerMenu);
            app.RemoveLayerMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveLayerMenuSelected, true);
            app.RemoveLayerMenu.Accelerator = 'R';
            app.RemoveLayerMenu.Text = 'Remove Layer';
            app.RemoveLayerMenu.Tag = 'NeedsLayers';
            app.NavigateTopMenu = uimenu(app.LayerMenu);
            app.NavigateTopMenu.MenuSelectedFcn = createCallbackFcn(app, @NavigateTopMenuSelected, true);
            app.NavigateTopMenu.Enable = 'off';
            app.NavigateTopMenu.Separator = 'on';
            app.NavigateTopMenu.Accelerator = 'T';
            app.NavigateTopMenu.Text = 'Navigate Top';
            app.NavigateTopMenu.Tag = 'NeedsLayers';
            app.NavigateUpwardMenu = uimenu(app.LayerMenu);
            app.NavigateUpwardMenu.MenuSelectedFcn = createCallbackFcn(app, @NavigateUpwardMenuSelected, true);
            app.NavigateUpwardMenu.Enable = 'off';
            app.NavigateUpwardMenu.Accelerator = 'U';
            app.NavigateUpwardMenu.Text = 'Navigate Upward';
            app.NavigateUpwardMenu.Tag = 'NeedsLayers';
            app.NavigateDownwardMenu = uimenu(app.LayerMenu);
            app.NavigateDownwardMenu.MenuSelectedFcn = createCallbackFcn(app, @NavigateDownwardMenuSelected, true);
            app.NavigateDownwardMenu.Enable = 'off';
            app.NavigateDownwardMenu.Accelerator = 'D';
            app.NavigateDownwardMenu.Text = 'Navigate Downward';
            app.NavigateDownwardMenu.Tag = 'NeedsLayers';
            app.NavigateBottomMenu = uimenu(app.LayerMenu);
            app.NavigateBottomMenu.MenuSelectedFcn = createCallbackFcn(app, @NavigateBottomMenuSelected, true);
            app.NavigateBottomMenu.Enable = 'off';
            app.NavigateBottomMenu.Accelerator = 'B';
            app.NavigateBottomMenu.Text = 'Navigate Bottom';
            app.NavigateBottomMenu.Tag = 'NeedsLayers';
            app.NavigateBackgroundMenu = uimenu(app.LayerMenu);
            app.NavigateBackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @NavigateBackgroundMenuSelected, true);
            app.NavigateBackgroundMenu.Accelerator = 'G';
            app.NavigateBackgroundMenu.Text = 'Navigate Background';
            app.BringForwardMenu = uimenu(app.LayerMenu);
            app.BringForwardMenu.MenuSelectedFcn = createCallbackFcn(app, @BringForwardMenuSelected, true);
            app.BringForwardMenu.Accelerator = 'J';
            app.BringForwardMenu.Text = 'Bring Forward';
            app.BringForwardMenu.Tag = 'NeedsTwoLayers';
            app.SendBackwardMenu = uimenu(app.LayerMenu);
            app.SendBackwardMenu.MenuSelectedFcn = createCallbackFcn(app, @SendBackwardMenuSelected, true);
            app.SendBackwardMenu.Accelerator = 'K';
            app.SendBackwardMenu.Text = 'Send Backward';
            app.SendBackwardMenu.Tag = 'NeedsTwoLayers';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.SetCanvasSizeandColorMenu = uimenu(app.ImageMenu);
            app.SetCanvasSizeandColorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetCanvasSizeandColorMenuSelected, true);
            app.SetCanvasSizeandColorMenu.Accelerator = 'C';
            app.SetCanvasSizeandColorMenu.Text = 'Set Canvas Size and Color';
            app.ImportImageMenu = uimenu(app.ImageMenu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.ImportImageMenu.Separator = 'on';
            app.ImportImageMenu.Accelerator = 'I';
            app.ImportImageMenu.Text = 'Import Image';
            app.ShiftandScaleMenu = uimenu(app.ImageMenu);
            app.ShiftandScaleMenu.MenuSelectedFcn = createCallbackFcn(app, @ShiftandScaleMenuSelected, true);
            app.ShiftandScaleMenu.Accelerator = 'O';
            app.ShiftandScaleMenu.Text = 'Shift and Scale';
            app.PickMaskValueMenu = uimenu(app.ImageMenu);
            app.PickMaskValueMenu.MenuSelectedFcn = createCallbackFcn(app, @PickMaskValueMenuSelected, true);
            app.PickMaskValueMenu.Separator = 'on';
            app.PickMaskValueMenu.Text = 'Pick Mask Value';
            app.SetMaskParametersMenu = uimenu(app.ImageMenu);
            app.SetMaskParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SetMaskParametersMenuSelected, true);
            app.SetMaskParametersMenu.Accelerator = 'M';
            app.SetMaskParametersMenu.Text = 'Set Mask Parameters';
            app.ColorAdjustmentsMenu = uimenu(app.ImageMenu);
            app.ColorAdjustmentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorAdjustmentsMenuSelected, true);
            app.ColorAdjustmentsMenu.Accelerator = 'A';
            app.ColorAdjustmentsMenu.Text = 'Color Adjustments';
            app.ImagePreview = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEE461Project2Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end